classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GenerateButton     matlab.ui.control.Button
        F3EditField        matlab.ui.control.NumericEditField
        F3EditFieldLabel   matlab.ui.control.Label
        F2EditField        matlab.ui.control.NumericEditField
        F2EditFieldLabel   matlab.ui.control.Label
        F1EditField        matlab.ui.control.NumericEditField
        F1EditFieldLabel   matlab.ui.control.Label
        DoneButton         matlab.ui.control.Button
        wsEditField        matlab.ui.control.NumericEditField
        wsEditFieldLabel   matlab.ui.control.Label
        wpEditField        matlab.ui.control.NumericEditField
        wpEditFieldLabel   matlab.ui.control.Label
        cutoffSlider       matlab.ui.control.Slider
        cutoffSliderLabel  matlab.ui.control.Label
        UIAxes4            matlab.ui.control.UIAxes
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        wave;
        fs=5e6% Description
    end
    
    methods (Access = private)        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Define the sampling frequency and time vector
           % fs = 5e6; % Sampling frequency (Hz)
            %t = 0:1/app.fs:2e-6; % Time vector (1 second duration)

            % Generate the input wave
%             wave = 2+cos(2*pi*500000*t) + cos(2*pi*1000000*t);
            %cutoff=app.cutoffSlider.Value*100;
            fc = app.cutoffSlider.Value*100; % Cutoff frequency (Hz)
            rp = app.wpEditField.Value; % Passband ripple (dB)
            rs = app.wsEditField.Value; % Stopband attenuation (dB)
            [N, Wn] = ellipord(fc/(app.fs/2), fc*1.2/(app.fs/2), rp, rs); % Order and cutoff frequencies
            [b, a] = ellip(N, rp, rs, Wn); % Elliptic filter coefficients

            % Convert to cascade IIR filter form
            sos = tf2sos(b, a); % Second-order sections (SOS) representation

            % Create a cell array to store the binary representations
binary_coeff = cell(size(sos));

% Convert each coefficient to its binary representation
for i = 1:size(sos, 1)
    for j = 1:size(sos, 2)
        binary_coeff{i, j} = fi_to_bin(sos(i, j));
    end
end

% Open a file for writing
file_id = fopen('input.txt', 'w');

% Write the binary coefficients to the file
fprintf(file_id,'1111111111111111\n');
for i = 1:size(binary_coeff, 1)
    for j = 1:size(binary_coeff, 2)
        fprintf(file_id, '%s ', binary_coeff{i, j});
        fprintf(file_id, '\n');
    end
end

fprintf(file_id,'1111111111111110\n');
for i = 1:length(app.wave)-1
    number = fi_to_bin(app.wave(i));
    fprintf(fopen("input.txt", 'a'), '%s\n', number);  % Write binary string to file
end
fclose('all');  % Close all open files
% Read the file contents line by line
fileID = fopen('input.txt', 'r');
fileContents = textscan(fileID, '%s', 'Delimiter', '\n');
fclose(fileID);

% Send each line over UART
serialPort = serial('COM12','BaudRate', 9600); % Replace with your serial port
fopen(serialPort);
for i = 1:length(fileContents{1})
    line = fileContents{1}{i};
    binaryChunk = bin2dec(line);
    fwrite(serialPort, binaryChunk, 'uint16');
    pause(0.01); % Add a small delay between transmissions
end
fclose(serialPort);
delete(serialPort);


        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
t = 0:1/app.fs:1e-4;
f1 = app.F1EditField.Value*100;
f2 = app.F2EditField.Value*100;
f3 = app.F3EditField.Value*100;

% Generate complex exponential signals
wave1 = cos(2*pi*f1*t);
wave2 = cos(2*pi*f2*t);
wave3 = cos(2*pi*f3*t);

% Take the absolute value to get the magnitude
app.wave = abs(wave1) + abs(wave2) + abs(wave3);

% Plot the magnitude signal in the time domain
plot(app.UIAxes, t, app.wave);

% Perform Fourier analysis
n = length(app.wave);
y = fftshift(fft(app.wave)) / n;
f = (-n/2:n/2-1) * app.fs / n; % Frequency axis in Hertz

% Plot the magnitude spectrum
stem(app.UIAxes2, f, abs(y), 'filled', 'LineWidth', 2);
xlabel(app.UIAxes2, 'Frequency (Hz)');
ylabel(app.UIAxes2, 'Magnitude');
%xlim(app.UIAxes2,[-1e4,1e4]);

        end

        % Callback function
        function receiveButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.cutoffSliderLabel = uilabel(app.UIFigure);
            app.cutoffSliderLabel.Text = 'cutoff';
            app.cutoffSlider = uislider(app.UIFigure);
            app.cutoffSlider.Limits = [100 2500];
            app.cutoffSlider.MajorTicks = [100 500 900 1300 1700 2100 2500];
            app.cutoffSlider.Value = 350;
            app.wpEditFieldLabel = uilabel(app.UIFigure);
            app.wpEditFieldLabel.Text = 'wp%';
            app.wpEditField = uieditfield(app.UIFigure, 'numeric');
            app.wpEditField.Limits = [0 100];
            app.wpEditField.Value = 2.5;
            app.wsEditFieldLabel = uilabel(app.UIFigure);
            app.wsEditFieldLabel.Text = 'ws%';
            app.wsEditField = uieditfield(app.UIFigure, 'numeric');
            app.wsEditField.Limits = [0 100];
            app.wsEditField.Value = 80;
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.F1EditFieldLabel = uilabel(app.UIFigure);
            app.F1EditFieldLabel.Text = 'F1';
            app.F1EditField = uieditfield(app.UIFigure, 'numeric');
            app.F1EditField.Limits = [100 2500];
            app.F1EditField.Value = 100;
            app.F2EditFieldLabel = uilabel(app.UIFigure);
            app.F2EditFieldLabel.Text = 'F2';
            app.F2EditField = uieditfield(app.UIFigure, 'numeric');
            app.F2EditField.Limits = [100 2500];
            app.F2EditField.Value = 300;
            app.F3EditFieldLabel = uilabel(app.UIFigure);
            app.F3EditFieldLabel.Text = 'F3';
            app.F3EditField = uieditfield(app.UIFigure, 'numeric');
            app.F3EditField.Limits = [100 2500];
            app.F3EditField.Value = 500;
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end